classdef Sensor < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        SensorReading               matlab.ui.Figure
        Graph                       matlab.ui.control.UIAxes
        SamplesLabel                matlab.ui.control.Label
        Samples                     matlab.ui.control.NumericEditField
        DataAvailablilityLampLabel  matlab.ui.control.Label
        DataAvailablilityLamp       matlab.ui.control.Lamp
        PortEditFieldLabel          matlab.ui.control.Label
        PortEditField               matlab.ui.control.EditField
        IdleLabel                   matlab.ui.control.Label
        Connect                     matlab.ui.control.Button
        Start                       matlab.ui.control.Button
        StopButton                  matlab.ui.control.Button
        StartStop                   matlab.ui.control.StateButton
    end


    properties (Access = public)
        s % Serial
        state %
    end


    methods (Access = private)

        % Button pushed function: Connect
        function ConnectButtonPushed(app, event)
            delete(instrfindall);            
            cla(app.Graph);
            % Serial comm variables
            try
                app.s = serial(app.PortEditField.Value); % need to determine which port is being connected
                set(app.s,'Timeout',1);
                fopen(app.s);
                app.IdleLabel.Text = 'Connected!';
            catch
                app.IdleLabel.Text = 'Error: cannot find the port';
                fprintf('Error: cannot find the port.\n');
                return;
            end
            app.DataAvailablilityLamp.Color = 'black';
        end

        % Button pushed function: Start
        function StartPushed(app, event)
            % Data variables
            samples = app.Samples.Value;
            time = zeros(samples,1);
            temperature = zeros(samples,1);
            readings = zeros(samples,8);
            
            % Plotting variables
            h = animatedline(app.Graph);
            h.Marker = 'o';
            h.Color = 'blue';
            clearpoints(h);
            drawnow
            begin_time = clock;
            tic;
            % Actual program starts here
            for i = 1:samples
                if app.state == false
                    app.IdleLabel.Text = 'Terminated';
                    app.DataAvailablilityLamp.Color = 'yellow';
                    break
                else    
                    app.IdleLabel.Text = 'Reading...';
                    
                    % Get data and time
                    data = zeros(240,1);
                    try
                        [data,count,msg] = fgetl(app.s);
                    catch
                        app.IdleLabel.Text = 'Error: connect a port';
                        fprintf('Error: connect a port.\n');
                        return;
                    end
                    time = toc;
                    
                    if msg == "A timeout occurred before the Terminator was reached."
                        temperature = nan;
                        app.DataAvailablilityLamp.Color = 'red';
                        app.IdleLabel.Text = 'No input data';
                        fprintf('Error: No input data at Sample# %d.\n', i);
                    else
                        % Convert BIN to Celsius
                        str_temp = data;
                        str_temp(192) = '1';
                        try
                            str_trun =  char(bin2dec(reshape(str_temp(169:192),8,[]).').');
                            str_flip = fliplr(str_trun);
                            temperature = hex2dec(str_flip)/10;
                        catch
                            app.IdleLabel.Text = 'Bad input';
                            fprintf('Error: Bad input at Sample# %d.\n', i);
                            temperature = nan;
                        end
                        app.DataAvailablilityLamp.Color = 'green';
                    end
                    
                    % Animate graph
                    addpoints(h,time,temperature);
                    drawnow
                    
                    % Export to file
                    readings(i,:) = [begin_time, time temperature];
                    csvwrite('Readings.csv',readings)
                end
            end
            
            app.IdleLabel.Text = 'Done';
            app.DataAvailablilityLamp.Color = 'black';
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            return;
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.SensorReading = uifigure;
            app.SensorReading.Name = 'UI Figure';
            app.Graph = uiaxes(app.SensorReading);
            title(app.Graph, 'Temperature vs Time')
            xlabel(app.Graph, 'Time (Second)')
            ylabel(app.Graph, 'Temperature (C)')
            app.SamplesLabel = uilabel(app.SensorReading);
            app.SamplesLabel.Text = 'Samples ';
            app.Samples = uieditfield(app.SensorReading, 'numeric');
            app.Samples.Limits = [1 Inf];
            app.Samples.Value = 50;
            app.DataAvailablilityLampLabel = uilabel(app.SensorReading);
            app.DataAvailablilityLampLabel.Text = 'Data Availablility';
            app.DataAvailablilityLamp = uilamp(app.SensorReading);
            app.PortEditFieldLabel = uilabel(app.SensorReading);
            app.PortEditFieldLabel.Text = 'Port';
            app.PortEditField = uieditfield(app.SensorReading, 'text');
            app.PortEditField.Value = 'com3';
            app.IdleLabel = uilabel(app.SensorReading);
            app.IdleLabel.Text = 'Idle';
            app.Connect = uibutton(app.SensorReading, 'push');
            app.Connect.ButtonPushedFcn = createCallbackFcn(app, @ConnectButtonPushed, true);
            app.Connect.Text = 'Connect/Reset';
            app.Start = uibutton(app.SensorReading, 'push');
            app.Start.ButtonPushedFcn = createCallbackFcn(app, @StartPushed, true);
            app.Start.Text = 'Start';
            app.StopButton = uibutton(app.SensorReading, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop';
            app.StartStop = uibutton(app.SensorReading, 'state');
            app.StartStop.ValueChangedFcn = createCallbackFcn(app, @StartStopValueChanged, true);
            app.StartStop.Text = 'Start';
        end
    end

    methods (Access = public)

        % Construct app
        function app = Sensor

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.SensorReading)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SensorReading)
        end
    end
end