classdef Run_Monitoring_Without_Graph < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        Monitoring                  matlab.ui.Figure
        DataAvailablilityLampLabel  matlab.ui.control.Label
        DataAvailablilityLamp       matlab.ui.control.Lamp
        PortEditFieldLabel          matlab.ui.control.Label
        PortEditField               matlab.ui.control.EditField
        IdleLabel                   matlab.ui.control.Label
        ConnectButton               matlab.ui.control.Button
        StartButton                 matlab.ui.control.Button
        p1EditFieldLabel            matlab.ui.control.Label
        p1EditField                 matlab.ui.control.NumericEditField
        p2EditFieldLabel            matlab.ui.control.Label
        p2EditField                 matlab.ui.control.NumericEditField
        ActivateLinCoefffxp1xp2CheckBox  matlab.ui.control.CheckBox
        PauseButton                 matlab.ui.control.Button
        StopButton                  matlab.ui.control.Button
        SaveMultipleFilesCheckBox   matlab.ui.control.CheckBox
        SecondEditField             matlab.ui.control.NumericEditField
        SecLabel                    matlab.ui.control.Label
        IDLabel                     matlab.ui.control.Label
        EveryLabel                  matlab.ui.control.Label
    end

    properties (Access = public)
        s % Serial
        fileName % Saved CSV file
        %         h % Animation Variable
        %         h_cal % Animation for Calibrated data
        sc % warning sound structure
        readings % Data
        abs_time
        rel_time
        temperature
        cal_temperature
        isCorrupted % Check for a matched checksum
        id
        country
        start % Tells that the program is running
        i_cur % Current index
        i_saved % Last saved index
        num % this autosave number
    end
    
    methods (Access = private)
        
        % Load files, Initialize arrays, Prepare figure, and Config label.
        function SetupVariables(app)
            %             cla(app.Graph)
            
            app.fileName = sprintf('Readings %s.csv', datestr(now, 'yyyy-mm-dd-hh-MM-ss'));
            app.num = 1;
            app.i_saved = 1;
            app.p1EditField.Editable = 'off';
            app.p2EditField.Editable = 'off';
            app.PortEditField.Editable = 'off';
            app.SecondEditField.Editable = 'off';
            app.ActivateLinCoefffxp1xp2CheckBox.Enable = 'off';
            app.SaveMultipleFilesCheckBox.Enable = 'off';
            app.StartButton.Enable = 'off';
            app.ConnectButton.Enable = 'off';
            
            % Load warning sound
            app.sc = load('gong.mat');
            
            
            % Plotting variables
            %             app.h = animatedline(app.Graph);
            %             app.h_cal = animatedline(app.Graph);
            %             legend(app.Graph,'Raw','Cal');
            %             app.h.Marker = 'o';
            %             app.h.Color = 'blue';
            %             app.h_cal.Marker = 'o';
            %             app.h_cal.Color = 'red';
            %             clearpoints(app.h);
            %             clearpoints(app.h_cal);
            %             drawnow
            
            pause on
        end
        
        function Done(app)
            app.IdleLabel.Text = 'Done';
            app.p1EditField.Editable = 'on';
            app.p2EditField.Editable = 'on';
            app.PortEditField.Editable = 'on';
            app.SecondEditField.Editable = 'on';
            app.ActivateLinCoefffxp1xp2CheckBox.Enable = 'on';
            app.SaveMultipleFilesCheckBox.Enable = 'on';
            app.StartButton.Enable = 'on';
            app.ConnectButton.Enable = 'on';
            app.DataAvailablilityLamp.Color = 'black';
            
            pause off
        end
        
        function ErrorPort(app)
            app.IdleLabel.Text = 'Connect a port first';
            fprintf('Error: connect a port first.\n');
            app.p1EditField.Editable = 'on';
            app.p2EditField.Editable = 'on';
            app.PortEditField.Editable = 'on';
            app.SecondEditField.Editable = 'on';
            app.ActivateLinCoefffxp1xp2CheckBox.Enable = 'on';
            app.SaveMultipleFilesCheckBox.Enable = 'on';
            app.StartButton.Enable = 'on';
            app.ConnectButton.Enable = 'on';
        end
        
        function ConvertAndCalculate(app, data, i)
            % Checksum
            try
                data_dec = bin2dec(reshape(data(1:240),8,[]).');
                result = 0;
                for j = 2:length(data_dec)-3
                    result = bitxor(data_dec(j),result,'uint8');
                end
                app.isCorrupted(i) = (result ~= data_dec(28));
            catch % Catch actual bad input even before any processes
                app.IdleLabel.Text = "Bad Data input";
                fprintf('Error: Bad Input at Sample# %d at %.4f Data: %s\n', i,app.rel_time(i), data);
                app.isCorrupted(i) = 1;
                app.id(i) = nan;
                app.country(i) = nan;
                app.temperature(i) = nan;
                app.cal_temperature(i) = nan;
                soundsc(app.sc.y); % play warning sound
                app.DataAvailablilityLamp.Color = 'yellow';
                return;
            end
            if ~app.isCorrupted(i)
                try
                    data(192) = '1';
                    
                    % ID
                    id_trun =  char(bin2dec(reshape(data(9:88),8,[]).').');
                    id_flip = fliplr(id_trun);
                    app.id(i) = hex2dec(id_flip);
                    
                    % Country number
                    country_trun = char(bin2dec(reshape(data(89:120),8,[]).').');
                    country_flip = fliplr(country_trun);
                    app.country(i) = hex2dec(country_flip);
                    
                    % Temperature
                    temp_trun =  char(bin2dec(reshape(data(169:192),8,[]).').');
                    temp_flip = fliplr(temp_trun);
                    app.temperature(i) = hex2dec(temp_flip)/10;
                    app.IDLabel.Text = sprintf("ID: %d%d",app.country(i),app.id(i));
                    
                    if (app.ActivateLinCoefffxp1xp2CheckBox.Value && ~isnan(app.temperature(i))) % apply calibration coefficients if checked
                        app.cal_temperature(i) = app.p2EditField.Value + app.temperature(i)*app.p1EditField.Value;
                    else
                        app.cal_temperature(i) = nan;
                    end
                    app.DataAvailablilityLamp.Color = 'green';
                catch % Catch any error occurred between conversions
                    app.IdleLabel.Text = 'Conversion Failed';
                    fprintf('Error: Conversion Failed at Sample# %d at %.4f Data: %s.\n', i,app.rel_time(i),data);
                    app.temperature(i) = nan;
                    app.cal_temperature(i) = nan;
                    soundsc(app.sc.y); % play warning sound
                    app.DataAvailablilityLamp.Color = 'magenta';
                end
            end
        end
        
        function GettingData(app,i)
            app.IdleLabel.Text = 'Reading...';
            
            % Get data and rel_time
            try
                [data,count,msg] = fgetl(app.s);
            catch
                ErrorPort(app);
                return;
            end
            app.abs_time(i,:) = clock;
            app.rel_time(i) = toc;
            
            if isempty(data)
                app.isCorrupted(i) = nan;
                app.id(i) = nan;
                app.country(i) = nan;
                app.temperature(i) = nan;
                app.cal_temperature(i) = nan;
                app.DataAvailablilityLamp.Color = 'red';
                app.IdleLabel.Text = 'No input data';
                fprintf('Error: No input data at Sample# %d at %.4f.\n', i, app.rel_time(i));
            else
                % Convert BIN to Celsius
                ConvertAndCalculate(app, data, i);
            end
            fprintf('Sample#%d Temp: %.2d Cal_Temp: %.2d Elapsed Time: %.4f TimeStamp: %d %d %d %d %d %.4f isCorrupted: %d\n',i,app.temperature(i),app.cal_temperature(i),app.rel_time(i),app.abs_time(i,1),app.abs_time(i,2),app.abs_time(i,3),app.abs_time(i,4),app.abs_time(i,5),app.abs_time(i,6),app.isCorrupted(i))
            % Animate graph
            %             addpoints(app.h, app.rel_time(i), app.temperature(i));
            %             drawnow
            try
                if app.ActivateLinCoefffxp1xp2CheckBox.Value
                    %                     addpoints(app.h_cal, app.rel_time(i), app.cal_temperature(i));
                    %                     drawnow
                end
                app.readings(i,:) = [app.abs_time(i,:) app.rel_time(i) app.temperature(i) app.cal_temperature(i) app.isCorrupted(i)];
            catch
                fprintf("Program Terminated!\n");
                return;
            end
        end
        
        function MonitoringStart(app,i)
            tic;
            while true
                app.i_cur = i;
                switch app.start
                    case 1 % StartButton
                        GettingData(app,i)
                        i = i + 1;
                        if app.SaveMultipleFilesCheckBox.Value
                            AutoSave(app);
                        end
                    case 0 % PauseButton
                        app.DataAvailablilityLamp.Color = 'blue';
                        pause(1.5)
                    case -1 % StopButton
                        app.IdleLabel.Text = 'Stopping...!';
                        fprintf("Stopping...\n");
                        break;
                    otherwise % Prevent unexpected error
                        app.IdleLabel.Text = 'Fatal Error!';
                        fprintf("Encounter unexpected error\n");
                        MonitoringCloseRequest(app, event);
                end
            end
            SaveDataEnd(app);
            Done(app);
        end
        
        function AutoSave(app) % save every X second
            a = floor(toc/app.SecondEditField.Value);
            if a > app.num - 1
                autosave_name = sprintf('Part%d %s',app.num,app.fileName);
                try
                    csvwrite(autosave_name, app.readings(app.i_saved:app.i_cur-1,:))
                    fprintf('AutoSaved in "%s"! \n', autosave_name);
                    app.i_saved = app.i_cur;
                catch
                    fprintf('Error saving file in "%s"! \n', autosave_name);
                end
                app.num = app.num + 1;
            else
                return
            end
        end
        
        function SaveDataEnd(app)
            % Export to a single file
            if ~app.SaveMultipleFilesCheckBox.Value
                csvwrite(app.fileName, app.readings)
                fprintf('Data Successfully Saved in "%s"! \n', app.fileName);
            else
                autosave_name = sprintf('Part%d %s',app.num,app.fileName);
                try
                    csvwrite(autosave_name, app.readings(app.i_saved:app.i_cur-1,:))
                    fprintf('AutoSaved in "%s"! \n', autosave_name);
                catch
                    fprintf('Error saving file in "%s"! \n', autosave_name);
                end
            end
        end
    end

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.start = -1;
        end

        % Button pushed function: ConnectButton
        function ConnectButtonButtonPushed(app, event)
            delete(instrfind);
            %             cla(app.Graph);
            % Serial comm
            try
                app.s = serial(app.PortEditField.Value); % need to determine which port is being connected
                set(app.s,'timeout',1);
                fopen(app.s);
                app.IdleLabel.Text = 'Connected!';
                fprintf('Connected to the port!\n');
                flushinput(app.s);
            catch
                app.IdleLabel.Text = 'Cannot find the port!';
                fprintf('Error: cannot find the port!\n');
                delete(app.s);
                return;
            end
        end

        % Close request function: Monitoring
        function MonitoringCloseRequest(app, event)
            res = questdlg('Are you sure you want to quit?','Terminating','Yes','No','No');
            switch res
                case 'Yes'
                    autosave_name = sprintf('Part%d %s',app.num,app.fileName);
                    try
                        csvwrite(autosave_name, app.readings(app.i_saved:app.i_cur-1,:))
                        fprintf('AutoSaved in "%s"! \n', autosave_name);
                        app.i_saved = app.i_cur;
                        fprintf('Error saving file in "%s"! \n', autosave_name);
                        fclose(app.s);
                    catch
                        
                    end
                    delete(app);
                    delete(instrfind);
                    fprintf("Program terminated\n");
                case 'No'
                    return;
                case ''
                    return;
            end
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            switch app.start
                case -1 % Just launch program, continue
                    fprintf("Start\n");
                    SetupVariables(app);
                    try
                        flushinput(app.s);
                        app.start = 1;
                        i = 1; % Start over
                        try
                            MonitoringStart(app,i);
                        catch
                            
                        end
                    catch
                        app.start = -1;
                        ErrorPort(app);
                        return;
                    end
                case 0 % Program paused, continue
                    fprintf("Continue\n");
                    try
                        flushinput(app.s);
                        app.start = 1;
                    catch
                        app.start = -1;
                        ErrorPort(app);
                        return;
                    end
                case 1 % Program already started
                    app.IdleLabel.Text = 'Program already started!';
                    fprintf('Program already started!\n');
                    return;
                otherwise % Prevent unexpected error
                    app.IdleLabel.Text = 'Fatal Error!';
                    fprintf("Encounter unexpected error\n");
                    MonitoringCloseRequest(app, event);
            end
        end

        % Button pushed function: PauseButton
        function PauseButtonPushed(app, event)
            if app.start == 1
                app.start = 0;
                app.IdleLabel.Text = 'Paused!';
                fprintf("Paused\n");
                app.StartButton.Enable = 'on';
            else
                app.IdleLabel.Text = 'Program not running!';
                fprintf("Error: Program not running. Cannot pause!\n");
                return
            end
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            if app.start == 1 || app.start == 0
                app.start = -1;
            else
                app.IdleLabel.Text = 'Program not running!';
                fprintf("Error: Program not running. Cannot stop!\n");
                return
            end
        end

        % Value changed function: SaveMultipleFilesCheckBox
        function ControlSecEnable(app, event)
            value = app.SaveMultipleFilesCheckBox.Value;
            if value
                app.SecondEditField.Editable = "on";
                app.SecondEditField.Enable = "on";
                app.EveryLabel.Enable = "on";
                app.SecLabel.Enable = "on";
            else
                app.SecondEditField.Editable = "off";
                app.SecondEditField.Enable = "off";
                app.EveryLabel.Enable = "off";
                app.SecLabel.Enable = "off";
            end
        end

        % Value changed function: ActivateLinCoefffxp1xp2CheckBox
        function Controlp1p2Enable(app, event)
            value = app.ActivateLinCoefffxp1xp2CheckBox.Value;
            if value
                app.p1EditField.Editable = "on";
                app.p2EditField.Editable = "on";
            else
                app.p1EditField.Editable = "off";
                app.p2EditField.Editable = "off";
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.Monitoring = uifigure;
            app.Monitoring.Name = 'UI Figure';
            app.Monitoring.CloseRequestFcn = createCallbackFcn(app, @MonitoringCloseRequest, true);
            app.DataAvailablilityLampLabel = uilabel(app.Monitoring);
            app.DataAvailablilityLampLabel.Text = 'Data Availablility';
            app.DataAvailablilityLamp = uilamp(app.Monitoring);
            app.PortEditFieldLabel = uilabel(app.Monitoring);
            app.PortEditFieldLabel.Text = 'Port';
            app.PortEditField = uieditfield(app.Monitoring, 'text');
            app.PortEditField.Value = 'COM5';
            app.IdleLabel = uilabel(app.Monitoring);
            app.IdleLabel.Text = 'Idle';
            app.ConnectButton = uibutton(app.Monitoring, 'push');
            app.ConnectButton.ButtonPushedFcn = createCallbackFcn(app, @ConnectButtonButtonPushed, true);
            app.ConnectButton.Text = 'Connect';
            app.StartButton = uibutton(app.Monitoring, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.p1EditFieldLabel = uilabel(app.Monitoring);
            app.p1EditFieldLabel.Text = 'p1';
            app.p1EditField = uieditfield(app.Monitoring, 'numeric');
            app.p1EditField.ValueDisplayFormat = '%11.6g';
            app.p1EditField.Value = 1.1078;
            app.p2EditFieldLabel = uilabel(app.Monitoring);
            app.p2EditFieldLabel.Text = 'p2';
            app.p2EditField = uieditfield(app.Monitoring, 'numeric');
            app.p2EditField.ValueDisplayFormat = '%11.6g';
            app.p2EditField.Value = -4.79753;
            app.ActivateLinCoefffxp1xp2CheckBox = uicheckbox(app.Monitoring);
            app.ActivateLinCoefffxp1xp2CheckBox.ValueChangedFcn = createCallbackFcn(app, @Controlp1p2Enable, true);
            app.ActivateLinCoefffxp1xp2CheckBox.Text = 'Activate Lin. Coeff. f(x) = p1*x+p2';
            app.ActivateLinCoefffxp1xp2CheckBox.Value = true;
            app.PauseButton = uibutton(app.Monitoring, 'push');
            app.PauseButton.ButtonPushedFcn = createCallbackFcn(app, @PauseButtonPushed, true);
            app.PauseButton.Text = 'Pause';
            app.StopButton = uibutton(app.Monitoring, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop';
            app.SaveMultipleFilesCheckBox = uicheckbox(app.Monitoring);
            app.SaveMultipleFilesCheckBox.ValueChangedFcn = createCallbackFcn(app, @ControlSecEnable, true);
            app.SaveMultipleFilesCheckBox.Text = 'Save Multiple Files';
            app.SaveMultipleFilesCheckBox.Value = true;
            app.SecondEditField = uieditfield(app.Monitoring, 'numeric');
            app.SecondEditField.Limits = [10 86400];
            app.SecondEditField.Value = 10;
            app.SecLabel = uilabel(app.Monitoring);
            app.SecLabel.Text = 'Sec';
            app.IDLabel = uilabel(app.Monitoring);
            app.IDLabel.Text = 'ID: ';
            app.EveryLabel = uilabel(app.Monitoring);
            app.EveryLabel.Text = 'Every';
        end
    end

    methods (Access = public)

        % Construct app
        function app = Run_Monitoring_Without_Graph

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.Monitoring)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.Monitoring)
        end
    end
end