classdef Run_Monitoring < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        Monitoring                  matlab.ui.Figure
        Graph                       matlab.ui.control.UIAxes
        DataAvailablilityLampLabel  matlab.ui.control.Label
        DataAvailablilityLamp       matlab.ui.control.Lamp
        PortEditFieldLabel          matlab.ui.control.Label
        PortEditField               matlab.ui.control.EditField
        IdleLabel                   matlab.ui.control.Label
        ConnectButton               matlab.ui.control.Button
        StartButton                 matlab.ui.control.Button
        p1EditFieldLabel            matlab.ui.control.Label
        p1EditField                 matlab.ui.control.NumericEditField
        p2EditFieldLabel            matlab.ui.control.Label
        p2EditField                 matlab.ui.control.NumericEditField
        Label                       matlab.ui.control.Label
        ActivateLinCoefffxp1xp2CheckBox  matlab.ui.control.CheckBox
        PauseButton                 matlab.ui.control.Button
        StopButton                  matlab.ui.control.Button
    end

    properties (Access = public)
        s % Serial
        fileName % Saved CSV file
        h % Animation Variable
        h_cal % Animation for Calibrated data
        sc % warning sound structure
        readings % Data
        abs_time
        rel_time
        temperature
        cal_temperature
        start % Tells that the program is running
        i_con % Index to continue the pause
        done % Tells how many complete operations have been done in a session
    end
    
    methods (Access = private)
        
        % Load files, Initialize arrays, Prepare figure, and Config label.
        function SetupVariables(app)
            cla(app.Graph)
            
            app.fileName = sprintf('Readings %s.csv', datestr(now, 'yyyy-mm-dd-hh-MM-ss'));
            app.p1EditField.Editable = 'off';
            app.p2EditField.Editable = 'off';
            app.PortEditField.Editable = 'off';
            app.ActivateLinCoefffxp1xp2CheckBox.Enable = 'off';
            app.StartButton.Enable = 'off';
            app.ConnectButton.Enable = 'off';
            
            % Load warning sound
            app.sc = load('gong.mat');
            
            %             % Data variables
            %             app.readings = zeros(app.samples,9);
            %             app.abs_time = zeros(app.samples,6);
            %             app.rel_time = zeros(app.samples,1);
            %             app.temperature = zeros(app.samples,1);
            %             app.cal_temperature = zeros(app.samples,1);
            
            % Plotting variables
            app.h = animatedline(app.Graph);
            app.h_cal = animatedline(app.Graph);
            legend(app.Graph,'Raw','Cal');
            app.h.Marker = 'o';
            app.h.Color = 'blue';
            app.h_cal.Marker = 'o';
            app.h_cal.Color = 'red';
            clearpoints(app.h);
            clearpoints(app.h_cal);
            drawnow
            
            pause on
        end
        
        function Done(app)
            app.IdleLabel.Text = 'Done';
            app.p1EditField.Editable = 'on';
            app.p2EditField.Editable = 'on';
            app.PortEditField.Editable = 'on';
            app.ActivateLinCoefffxp1xp2CheckBox.Enable = 'on';
            app.StartButton.Enable = 'on';
            app.ConnectButton.Enable = 'on';
            app.DataAvailablilityLamp.Color = 'black';
            
            pause off
        end
        
        function ConvertAndCalculate(app, data, i)
            str_temp = data;
            str_temp(192) = '1';
            try
                str_trun =  char(bin2dec(reshape(str_temp(169:192),8,[]).').');
                str_flip = fliplr(str_trun);
                app.temperature(i) = hex2dec(str_flip)/10;
                if (app.ActivateLinCoefffxp1xp2CheckBox.Value && ~isnan(app.temperature(i))) % apply calibration coefficients if checked
                    app.cal_temperature(i) = app.p2EditField.Value + app.temperature(i)*app.p1EditField.Value;
                else
                    app.cal_temperature(i) = nan;
                end
                app.DataAvailablilityLamp.Color = 'green';
            catch
                app.IdleLabel.Text = 'Bad input';
                fprintf('Error: Bad input at Sample# %d at %.4f.\n', i,app.rel_time(i));
                app.temperature(i) = nan;
                app.cal_temperature(i) = nan;
                soundsc(app.sc.y); % play warning sound
                app.DataAvailablilityLamp.Color = 'yellow';
            end
        end
        
        function ErrorPort(app)
            app.IdleLabel.Text = 'Connect a port first';
            fprintf('Error: connect a port first.\n');
            app.p1EditField.Editable = 'on';
            app.p2EditField.Editable = 'on';
            app.PortEditField.Editable = 'on';
            app.ActivateLinCoefffxp1xp2CheckBox.Enable = 'on';
            app.StartButton.Enable = 'on';
            app.ConnectButton.Enable = 'on';
        end
        
        function ReadingStart(app,i)
            app.IdleLabel.Text = 'Reading...';
            
            % Get data and rel_time
            if i == 1
                tic;
            end
            try
                [data,count,msg] = fgetl(app.s);
            catch
                ErrorPort(app);
                return;
            end
            app.abs_time(i,:) = clock;
            app.rel_time(i) = toc;
            
            if isempty(data)
                app.temperature(i) = nan;
                app.cal_temperature(i) = nan;
                app.DataAvailablilityLamp.Color = 'red';
                app.IdleLabel.Text = 'No input data';
                fprintf('Error: No input data at Sample# %d at %.4f.\n', i, app.rel_time(i));
            else
                % Convert BIN to Celsius
                ConvertAndCalculate(app, data, i);
            end
            
            % Animate graph
            addpoints(app.h, app.rel_time(i), app.temperature(i));
            drawnow
            try
                if app.ActivateLinCoefffxp1xp2CheckBox.Value
                    addpoints(app.h_cal, app.rel_time(i), app.cal_temperature(i));
                    drawnow
                end
                app.readings(i,:) = [app.abs_time(i,:) app.rel_time(i) app.temperature(i) app.cal_temperature(i)];
            catch
                fprintf("Program Terminated!\n");
                return;
            end
        end
        
        function index_con = MonitoringStart(app,i)
            while true
                index_con = i;
                switch app.start
                    case 1 % StartButton
                        ReadingStart(app,i)
                        i = i + 1;
                    case 0 % PauseButton
                        app.DataAvailablilityLamp.Color = 'blue';
                        pause(1.5)
                    case -1 % StopButton
                        app.IdleLabel.Text = 'Stopping...!';
                        fprintf("Stopping...\n");
                        break;
                    otherwise % Prevent unexpected error
                        app.IdleLabel.Text = 'Fatal Error!';
                        fprintf("Encounter unexpected error\n");
                        MonitoringCloseRequest(app, event);
                end
            end
            % Export to file
            csvwrite(app.fileName, app.readings)
            fprintf('Data Automatically Saved in "%s"! \n', app.fileName);
            
            Done(app);
            app.done = app.done + 1;
        end
    end

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.done = 0;
            app.start = -1;
        end

        % Button pushed function: ConnectButton
        function ConnectButtonButtonPushed(app, event)
            delete(instrfind);
            cla(app.Graph);
            % Serial comm
            try
                app.s = serial(app.PortEditField.Value); % need to determine which port is being connected
                set(app.s,'timeout',1);
                fopen(app.s);
                app.IdleLabel.Text = 'Connected!';
                fprintf('Connected to the port!\n');
                flushinput(app.s);
            catch
                app.IdleLabel.Text = 'Cannot find the port!';
                fprintf('Error: cannot find the port!\n');
                delete(app.s);
                return;
            end
        end

        % Close request function: Monitoring
        function MonitoringCloseRequest(app, event)
            res = questdlg('Are you sure you want to quit?','Terminating','Yes','No','No');
            switch res
                case 'Yes'
                    if ismember(app.start,[-1,0,1])
                        try
                            csvwrite(app.fileName, app.readings)
                            fprintf('Data Automatically Saved in "%s"!\n', app.fileName);
                        catch
                            fprintf('Closing a program without saving!\n');
                        end
                    else
                        fprintf('Closing a program without saving!\n');
                    end
                    try
                        fclose(app.s);
                    catch
                    end
                    delete(app);
                    delete(instrfind);
                case 'No'
                    return;
                case ''
                    return;
            end
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            switch app.start
                case -1 % Just launch program, continue
                    SetupVariables(app);
                    try
                        flushinput(app.s);
                        app.start = 1;
                        i = 1; % Start over
                    catch
                        app.start = -1;
                        ErrorPort(app);
                        return;
                    end
                case 0 % Program paused, continue
                    try
                        flushinput(app.s);
                        app.start = 1;
                        i = app.i_con;
                    catch
                        app.start = -1;
                        ErrorPort(app);
                        return;
                    end
                case 1 % Program already started
                    app.IdleLabel.Text = 'Program already started!';
                    fprintf('Program already started!\n');
                    return;
                otherwise % Prevent unexpected error
                    app.IdleLabel.Text = 'Fatal Error!';
                    fprintf("Encounter unexpected error\n");
                    MonitoringCloseRequest(app, event);
            end
            app.i_con = MonitoringStart(app,i);
        end

        % Button pushed function: PauseButton
        function PauseButtonPushed(app, event)
            if app.start == 1
                app.start = 0;
                app.IdleLabel.Text = 'Paused!';
                fprintf("Paused\n");
                app.StartButton.Enable = 'on';
            else
                app.IdleLabel.Text = 'Program not running!';
                fprintf("Error: Program not running. Cannot pause!\n");
                return
            end
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            if app.start == 1 || app.start == 0
                app.start = -1;
            else
                app.IdleLabel.Text = 'Program not running!';
                fprintf("Error: Program not running. Cannot stop!\n");
                return
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.Monitoring = uifigure;
            app.Monitoring.Name = 'UI Figure';
            app.Monitoring.CloseRequestFcn = createCallbackFcn(app, @MonitoringCloseRequest, true);
            app.Graph = uiaxes(app.Monitoring);
            title(app.Graph, 'Temperature vs Time')
            xlabel(app.Graph, 'Time (Second)')
            ylabel(app.Graph, 'Temperature (C)')
            app.DataAvailablilityLampLabel = uilabel(app.Monitoring);
            app.DataAvailablilityLampLabel.Text = 'Data Availablility';
            app.DataAvailablilityLamp = uilamp(app.Monitoring);
            app.PortEditFieldLabel = uilabel(app.Monitoring);
            app.PortEditFieldLabel.Text = 'Port';
            app.PortEditField = uieditfield(app.Monitoring, 'text');
            app.PortEditField.Value = 'COM5';
            app.IdleLabel = uilabel(app.Monitoring);
            app.IdleLabel.Text = 'Idle';
            app.ConnectButton = uibutton(app.Monitoring, 'push');
            app.ConnectButton.ButtonPushedFcn = createCallbackFcn(app, @ConnectButtonButtonPushed, true);
            app.ConnectButton.Text = 'Connect';
            app.StartButton = uibutton(app.Monitoring, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.p1EditFieldLabel = uilabel(app.Monitoring);
            app.p1EditFieldLabel.Text = 'p1';
            app.p1EditField = uieditfield(app.Monitoring, 'numeric');
            app.p1EditField.ValueDisplayFormat = '%11.6g';
            app.p1EditField.Value = 1.1078;
            app.p2EditFieldLabel = uilabel(app.Monitoring);
            app.p2EditFieldLabel.Text = 'p2';
            app.p2EditField = uieditfield(app.Monitoring, 'numeric');
            app.p2EditField.ValueDisplayFormat = '%11.6g';
            app.p2EditField.Value = -4.79753;
            app.Label = uilabel(app.Monitoring);
            app.Label.Text = '';
            app.ActivateLinCoefffxp1xp2CheckBox = uicheckbox(app.Monitoring);
            app.ActivateLinCoefffxp1xp2CheckBox.Text = 'Activate Lin. Coeff. f(x) = p1*x+p2';
            app.ActivateLinCoefffxp1xp2CheckBox.Value = true;
            app.PauseButton = uibutton(app.Monitoring, 'push');
            app.PauseButton.ButtonPushedFcn = createCallbackFcn(app, @PauseButtonPushed, true);
            app.PauseButton.Text = 'Pause';
            app.StopButton = uibutton(app.Monitoring, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop';
        end
    end

    methods (Access = public)

        % Construct app
        function app = Run_Monitoring

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.Monitoring)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.Monitoring)
        end
    end
end