classdef Run_Improved_V2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        SensorReading               matlab.ui.Figure
        Graph                       matlab.ui.control.UIAxes
        SamplesLabel                matlab.ui.control.Label
        SamplesEditField            matlab.ui.control.NumericEditField
        DataAvailablilityLampLabel  matlab.ui.control.Label
        DataAvailablilityLamp       matlab.ui.control.Lamp
        PortEditFieldLabel          matlab.ui.control.Label
        PortEditField               matlab.ui.control.EditField
        IdleLabel                   matlab.ui.control.Label
        ConnectButton               matlab.ui.control.Button
        StartButton                 matlab.ui.control.Button
        p1EditFieldLabel            matlab.ui.control.Label
        p1EditField                 matlab.ui.control.NumericEditField
        p2EditFieldLabel            matlab.ui.control.Label
        p2EditField                 matlab.ui.control.NumericEditField
        LinearCoefffxp1xp2Label     matlab.ui.control.Label
        ActivateCheckBox            matlab.ui.control.CheckBox
        ResetButton                 matlab.ui.control.Button
    end

    properties (Access = public)
        s % Serial
        fileName % Saved CSV file
        h % Animation Variable
        h_cal % Animation for Calibrated data
        sc % warning sound structure
        readings % Data
        samples
        abs_time
        rel_time
        temperature
        cal_temperature
        start % Tells that the program is running
        done % Tells how many complete operations have been done in a session
    end
    
    methods (Access = private)
        
        % Load files, Initialize arrays, Prepare figure, and Config label.
        function SetupVariables(app)
            app.start = 0;
            app.fileName = sprintf('Readings %s.csv', datestr(now, 'yyyy-mm-dd-hh-MM-ss'));
            app.p1EditField.Editable = 'off';
            app.p2EditField.Editable = 'off';
            app.SamplesEditField.Editable = 'off';
            app.PortEditField.Editable = 'off';
            app.ActivateCheckBox.Enable = 'off';
            app.StartButton.Enable = 'off';
            app.ConnectButton.Enable = 'off';
            
            % Load warning sound
            app.sc = load('gong.mat');
            
            % Data variables
            app.samples = app.SamplesEditField.Value;
            app.readings = zeros(app.samples,9);
            app.abs_time = zeros(app.samples,6);
            app.rel_time = zeros(app.samples,1);
            app.temperature = zeros(app.samples,1);
            app.cal_temperature = zeros(app.samples,1);
            
            % Plotting variables
            app.h = animatedline(app.Graph);
            app.h_cal = animatedline(app.Graph);
            legend(app.Graph,'Raw','Cal');
            app.h.Marker = 'o';
            app.h.Color = 'blue';
            app.h_cal.Marker = 'o';
            app.h_cal.Color = 'red';
            clearpoints(app.h);
            clearpoints(app.h_cal);
            drawnow
        end
        
        function Done(app)
            app.IdleLabel.Text = 'Done';
            app.p1EditField.Editable = 'on';
            app.p2EditField.Editable = 'on';
            app.SamplesEditField.Editable = 'on';
            app.PortEditField.Editable = 'on';
            app.ActivateCheckBox.Enable = 'on';
            app.StartButton.Enable = 'on';
            app.ConnectButton.Enable = 'on';
            app.DataAvailablilityLamp.Color = 'black';
        end
        
        function ConvertAndCalculate(app, data, i)
            str_temp = data;
            str_temp(192) = '1';
            try
                str_trun =  char(bin2dec(reshape(str_temp(169:192),8,[]).').');
                str_flip = fliplr(str_trun);
                app.temperature(i) = hex2dec(str_flip)/10;
                if (app.ActivateCheckBox.Value && ~isnan(app.temperature(i))) % apply calibration coefficients if checked
                    app.cal_temperature(i) = app.p2EditField.Value + app.temperature(i)*app.p1EditField.Value;
                else
                    app.cal_temperature(i) = nan;
                end
                app.DataAvailablilityLamp.Color = 'green';
            catch
                app.IdleLabel.Text = 'Bad input';
                fprintf('Error: Bad input at Sample# %d at %.4f.\n', i,app.rel_time(i));
                app.temperature(i) = nan;
                app.cal_temperature(i) = nan;
                soundsc(app.sc.y); % play warning sound
                app.DataAvailablilityLamp.Color = 'yellow';
            end
        end
        
        function ErrorPort(app)
            app.IdleLabel.Text = 'Error: connect a port first';
            fprintf('Error: connect a port first.\n');
            app.p1EditField.Editable = 'on';
            app.p2EditField.Editable = 'on';
            app.SamplesEditField.Editable = 'on';
            app.PortEditField.Editable = 'on';
            app.ActivateCheckBox.Enable = 'on';
            app.StartButton.Enable = 'on';
            app.ConnectButton.Enable = 'on';
        end
        
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.done = 0;
        end

        % Button pushed function: ConnectButton
        function ConnectButtonButtonPushed(app, event)
            delete(instrfind);
            cla(app.Graph);
            % Serial comm
            try
                app.s = serial(app.PortEditField.Value); % need to determine which port is being connected
                set(app.s,'timeout',1);
                fopen(app.s);
                app.IdleLabel.Text = 'Connected!';
                flushinput(app.s);
            catch
                app.IdleLabel.Text = 'Error: cannot find the port';
                fprintf('Error: cannot find the port.\n');
                delete(app.s);
                return;
            end
            
        end

        % Close request function: SensorReading
        function SensorReadingCloseRequest(app, event)
            res = questdlg('Are you sure you want to quit?','Terminating','Yes','No','No');
            switch res
                case 'Yes'
                    if app.done > 0
                        try
                            % Export to file
                            csvwrite(app.fileName, app.readings)
                            fprintf('Data Automatically Saved in "%s"!\n', app.fileName);
                            fprintf('%d files have been saved!\n', app.done)
                        catch
                            fprintf('Error: nothing is saved!\n');
                        end
                    else
                        if app.start
                            csvwrite(app.fileName, app.readings)
                            fprintf('Data saved! Program terminated before completed!\n');
                        else
                            fprintf('Closing a program without saving!\n');
                        end
                    end
                    try
                        fclose(app.s);
                    catch
                    end
                    delete(app);
                    delete(instrfind);
                case 'No'
                    return;
                case ''
                    return;
            end
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            SetupVariables(app);
            try
                flushinput(app.s);
            catch
                ErrorPort(app);
                return;
            end
            app.start = 1;
            % Actual program starts here
            for i = 1:app.samples
                app.IdleLabel.Text = 'Reading...';
                
                % Get data and rel_time
                if i == 1
                    tic;
                end
                try
                    [data,count,msg] = fgetl(app.s);
                catch
                    ErrorPort(app);
                    return;
                end
                app.abs_time(i,:) = clock;
                app.rel_time(i) = toc;
                
                if isempty(data)
                    app.temperature(i) = nan;
                    app.cal_temperature(i) = nan;
                    app.DataAvailablilityLamp.Color = 'red';
                    app.IdleLabel.Text = 'No input data';
                    fprintf('Error: No input data at Sample# %d at %.4f.\n', i, app.rel_time(i));
                else
                    % Convert BIN to Celsius
                    ConvertAndCalculate(app, data, i);
                end
                
                % Animate graph
                addpoints(app.h, app.rel_time(i), app.temperature(i));
                drawnow
                try
                    if app.ActivateCheckBox.Value
                        addpoints(app.h_cal, app.rel_time(i), app.cal_temperature(i));
                        drawnow
                    end
                    app.readings(i,:) = [app.abs_time(i,:) app.rel_time(i) app.temperature(i) app.cal_temperature(i)];
                catch
                    fprintf("Program Terminated!\n");
                    return;
                end
            end
            % Export to file
            csvwrite(app.fileName, app.readings)
            fprintf('Data Automatically Saved in "%s"! \n', app.fileName);
            Done(app);
            app.done = app.done + 1;
        end

        % Button pushed function: ResetButton
        function ResetButtonButtonPushed(app, event)
            cla(app.Graph);
            try
                flushinput(app.s);
            catch
                
            end
            app.IdleLabel.Text = 'Reset!';
            fprintf("Program reset! Ready for the next round!\n");
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.SensorReading = uifigure;
            app.SensorReading.Name = 'UI Figure';
            app.SensorReading.CloseRequestFcn = createCallbackFcn(app, @SensorReadingCloseRequest, true);
            app.Graph = uiaxes(app.SensorReading);
            title(app.Graph, 'Temperature vs Time')
            xlabel(app.Graph, 'Time (Second)')
            ylabel(app.Graph, 'Temperature (C)')
            app.SamplesLabel = uilabel(app.SensorReading);
            app.SamplesLabel.Text = 'Samples ';
            app.SamplesEditField = uieditfield(app.SensorReading, 'numeric');
            app.SamplesEditField.Limits = [1 Inf];
            app.SamplesEditField.Value = 100;
            app.DataAvailablilityLampLabel = uilabel(app.SensorReading);
            app.DataAvailablilityLampLabel.Text = 'Data Availablility';
            app.DataAvailablilityLamp = uilamp(app.SensorReading);
            app.PortEditFieldLabel = uilabel(app.SensorReading);
            app.PortEditFieldLabel.Text = 'Port';
            app.PortEditField = uieditfield(app.SensorReading, 'text');
            app.PortEditField.Value = 'COM5';
            app.IdleLabel = uilabel(app.SensorReading);
            app.IdleLabel.Text = 'Idle';
            app.ConnectButton = uibutton(app.SensorReading, 'push');
            app.ConnectButton.ButtonPushedFcn = createCallbackFcn(app, @ConnectButtonButtonPushed, true);
            app.ConnectButton.Text = 'Connect';
            app.StartButton = uibutton(app.SensorReading, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.p1EditFieldLabel = uilabel(app.SensorReading);
            app.p1EditFieldLabel.Text = 'p1';
            app.p1EditField = uieditfield(app.SensorReading, 'numeric');
            app.p1EditField.ValueDisplayFormat = '%11.6g';
            app.p1EditField.Value = 1.1078;
            app.p2EditFieldLabel = uilabel(app.SensorReading);
            app.p2EditFieldLabel.Text = 'p2';
            app.p2EditField = uieditfield(app.SensorReading, 'numeric');
            app.p2EditField.ValueDisplayFormat = '%11.6g';
            app.p2EditField.Value = -4.79753;
            app.LinearCoefffxp1xp2Label = uilabel(app.SensorReading);
            app.LinearCoefffxp1xp2Label.Text = 'Linear Coeff. f(x) = p1*x+p2';
            app.ActivateCheckBox = uicheckbox(app.SensorReading);
            app.ActivateCheckBox.Text = 'Activate';
            app.ActivateCheckBox.Value = true;
            app.ResetButton = uibutton(app.SensorReading, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonButtonPushed, true);
            app.ResetButton.Text = 'Reset';
        end
    end

    methods (Access = public)

        % Construct app
        function app = Run_Improved_V2

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.SensorReading)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SensorReading)
        end
    end
end